classdef MainMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainMenuUIFigure              matlab.ui.Figure
        RandomVariablesButton         matlab.ui.control.Button
        PairsofRandomVariablesButton  matlab.ui.control.Button
        RandomSignalsButton           matlab.ui.control.Button
        StatisticalDetectionButton    matlab.ui.control.Button
        ParametersEstimationButton    matlab.ui.control.Button
        RandXMenuLabel                matlab.ui.control.Label
    end

    
    properties (Access = public)
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RandomVariablesButton
        function RandomVariablesButtonPushed(app, event)
            % when this button is pushed, the Main Menu winodow closed and
            % the Random Variables window opens 
            delete(app.MainMenuUIFigure)
            RandomVariables;
        end

        % Button pushed function: PairsofRandomVariablesButton
        function PairsofRandomVariablesButtonPushed(app, event)
            % when this button is pushed, the Main Menu winodow closed and
            % the Pairs of Random Variables window opens 
            delete(app.MainMenuUIFigure)
            PairsRandomVariables;
        end

        % Button pushed function: RandomSignalsButton
        function RandomSignalsButtonPushed(app, event)
            % when this button is pushed, the Main Menu winodow closed and
            % the Random Signals window opens
            delete(app.MainMenuUIFigure)
            RandomSignals;
        end

        % Button pushed function: StatisticalDetectionButton
        function StatisticalDetectionButtonPushed(app, event)
            % when this button is pushed, the Main Menu winodow closed and
            % the Statistical Detection window opens
            delete(app.MainMenuUIFigure)
            StatisticalDetection;
        end

        % Button pushed function: ParametersEstimationButton
        function ParametersEstimationButtonPushed(app, event)
            % when this button is pushed, the Main Menu winodow closed and
            % the Parameter Estimation window opens
            delete(app.MainMenuUIFigure)
            ParameterEstimation;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainMenuUIFigure = uifigure('Visible', 'off');
            app.MainMenuUIFigure.Name = 'Main Menu';
            app.MainMenuUIFigure.Icon = 'UpcLogo.png';
            app.RandomVariablesButton = uibutton(app.MainMenuUIFigure, 'push');
            app.RandomVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @RandomVariablesButtonPushed, true);
            app.RandomVariablesButton.Text = 'Random Variables';
            app.PairsofRandomVariablesButton = uibutton(app.MainMenuUIFigure, 'push');
            app.PairsofRandomVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @PairsofRandomVariablesButtonPushed, true);
            app.PairsofRandomVariablesButton.Text = 'Pairs of Random Variables ';
            app.RandomSignalsButton = uibutton(app.MainMenuUIFigure, 'push');
            app.RandomSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @RandomSignalsButtonPushed, true);
            app.RandomSignalsButton.Text = 'Random Signals ';
            app.StatisticalDetectionButton = uibutton(app.MainMenuUIFigure, 'push');
            app.StatisticalDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticalDetectionButtonPushed, true);
            app.StatisticalDetectionButton.Text = 'Statistical Detection ';
            app.ParametersEstimationButton = uibutton(app.MainMenuUIFigure, 'push');
            app.ParametersEstimationButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersEstimationButtonPushed, true);
            app.ParametersEstimationButton.Text = 'Parameters Estimation';
            app.RandXMenuLabel = uilabel(app.MainMenuUIFigure);
            app.RandXMenuLabel.Text = 'RandX - Menu';
            % Show the figure after all components are created
            app.MainMenuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainMenu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainMenuUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainMenuUIFigure)
        end
    end
end