classdef PairsRandomVariables < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PairsofRandomVariablesUIFigure  matlab.ui.Figure
        Panel1                          matlab.ui.container.Panel
        Panel2                          matlab.ui.container.Panel
        Panel3                          matlab.ui.container.Panel
        xrangeEditFieldLabel            matlab.ui.control.Label
        xrangeEditField                 matlab.ui.control.NumericEditField
        RestartButton                   matlab.ui.control.Button
        yrangeEditFieldLabel            matlab.ui.control.Label
        yrangeEditField                 matlab.ui.control.NumericEditField
        mxEditFieldLabel                matlab.ui.control.Label
        mxEditField                     matlab.ui.control.NumericEditField
        myEditFieldLabel                matlab.ui.control.Label
        myEditField                     matlab.ui.control.NumericEditField
        sxEditFieldLabel                matlab.ui.control.Label
        sxEditField                     matlab.ui.control.NumericEditField
        syEditFieldLabel                matlab.ui.control.Label
        syEditField                     matlab.ui.control.NumericEditField
        rxyEditFieldLabel               matlab.ui.control.Label
        rxyEditField                    matlab.ui.control.NumericEditField
        PlotJointPDFMarginalsButton     matlab.ui.control.Button
        RealizationsofXEditFieldLabel   matlab.ui.control.Label
        RealizationsofXEditField        matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        PlotJointPDFButton_2            matlab.ui.control.Button
        RealizationsofXYEditFieldLabel  matlab.ui.control.Label
        RealizationsofXYEditField       matlab.ui.control.NumericEditField
        IndependentCheckBox             matlab.ui.control.CheckBox
        NotIndependentCheckBox          matlab.ui.control.CheckBox
        PlotRegressionLineButton        matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
    end

    
    properties (Access = public)
        % x & y for panel1
        x1 
        y1 
        % tiled layout for panel1
        t1
        % x & y for panel2
        x2 
        y2
        % tiled layout for panel2
        t2 
        % x & y for panel3
        x3 
        y3 
        % tiled layout for panel3
        t3 = 0 
        % marginals
        margx 
        margy 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotJointPDFMarginalsButton
        function PlotJointPDFMarginalsButtonPushed(app, event)
            % read x_lim and y_lim from EditFields
            x_lim = app.xrangeEditField.Value;
            y_lim = app.yrangeEditField.Value;
            % generate x1 and y1 vectors
            app.x1 = -x_lim:x_lim;
            app.y1 = -y_lim:y_lim;
            % read mx, my, sx, sy and rxy from EditFields
            mx = app.mxEditField.Value; my = app.myEditField.Value;
            sx = app.sxEditField.Value; sy = app.syEditField.Value;
            rxy = app.rxyEditField.Value;
            % use the read parameters to generate the parameters needed for
            % computing the joint pdf
            ct1 = 1/(2*pi*sx*sy*sqrt(1-rxy)); ct2 =1/(2*(1-rxy));
            rsxsy=2*rxy/(sx*sy);
            sx = 2*sx^2; sy = 2*sy^2;
            % computing the joint pdf
            for i=1:length(app.x1)
                for j=1:length(app.y1)
                    % compute the joint pdf
                    w(i,j)=ct1*exp(-ct2*((app.x1(i)-mx)^2/sx-rsxsy*(app.x1(i)-mx)*(app.y1(j)-my)+(app.y1(j)-my)^2/sy));
                end
            end
            
            app.margx = sum(w); app.margy = sum(w');
            % Clear existing axes on panel and set new layout as 6x1
            % The axis will not appear yet. app.Panel is the UI panel handle.
            app.t1 = tiledlayout(app.Panel1, 1, 3);
            % Access first subplot-tile for plotting
            ax1 = nexttile(app.t1);
            
            % plot the joint pdf
            mesh(ax1, app.x1, app.y1, w);
            title(ax1,'Joint PDF');
            %colormap(ax1,"turbo")
            
            % plot the marginal distributions 
            ax2 = nexttile(app.t1);
            plot(ax2, app.x1, app.margx, 'k');
            title(ax2,'Marginal');
            ax3 = nexttile(app.t1);
            plot(ax3, app.y1, app.margy, 'k');
            title(ax3,'Marginal');
        end

        % Button pushed function: PlotJointPDFButton_2
        function PlotJointPDFButton_2Pushed(app, event)
            % read N, a and b from EditFields
            N = app.RealizationsofXEditField.Value;
            a = app.aEditField.Value; b = app.bEditField.Value;
            app.x2=randn(1,N); % N Gaussian random variables
            app.y2 = a*randn(1,N) + b;
            % find min and max of x2 and y2
            min_x=min(app.x2);max_x=max(app.x2);
            min_y=min(app.x2);max_y=max(app.y2);
            % find abs using the min/max of x2 and y2
            absx=min_x:(max_x-min_x)/10:max_x;
            absy=min_y:(max_y-min_y)/10:max_y;
            for i=1:10
                for j=1:10
                     % computing the approx. joint pdf
                    M(i,j)=sum((app.x2<absx(i+1)&app.x2>=absx(i))&(app.y2<absy(j+1)&app.y2>=absy(j)));
                end
            end
            app.t2 = tiledlayout(app.Panel2, 1, 1);
            % Access first subplot-tile for plotting
            ax1 = nexttile(app.t2);
            % plot approximation of the joint pdf
            surf(ax1, absx(1:end-1),absy(1:end-1),M);
            %colormap(ax1,"turbo")
        end

        % Button pushed function: PlotRegressionLineButton
        function PlotRegressionLineButtonPushed(app, event)
            % when the random variables are independent 
            N = app.RealizationsofXYEditField.Value;
            app.x3 = rand(1,N);
            if app.IndependentCheckBox.Value == 1 && app.NotIndependentCheckBox.Value == 0
                % y3 is generated indepentently of x3
                app.y3 = rand(1,N);
                app.t3 = tiledlayout(app.Panel3, 1, 1);
                % Access first subplot-tile for plotting
                ax1 = nexttile(app.t3);
                % plot regression line
                scatter(ax1, app.x3,app.y3,25,"o")
                P = polyfit(app.x3,app.y3,1);
                yfit = P(1)*app.x3+P(2);
                hold(ax1,'on')
                plot(ax1, app.x3,yfit,'k-.');
            elseif app.IndependentCheckBox.Value == 0 && app.NotIndependentCheckBox.Value == 1 
                % y3 is generated using x3
                app.y3 = app.x3 + rand(1,N);
                app.t3 = tiledlayout(app.Panel3, 1, 1);
                % Access first subplot-tile for plotting
                ax1 = nexttile(app.t3);
                % plot regression line
                scatter(ax1, app.x3,app.y3,25,"o")
                P = polyfit(app.x3,app.y3,1);
                yfit = P(1)*app.x3+P(2);
                hold(ax1,'on')
                plot(ax1, app.x3,yfit,'k-.');
                % throw error if both the independent and not independent
                % checkboxes are checked
            elseif app.IndependentCheckBox.Value == 1 && app.NotIndependentCheckBox.Value == 1
                errordlg('The variables cannot be indepentend and not independent at the same time,','Error');
                % throw error if none of the independent and not independent
                % checkboxes are checked
            elseif app.IndependentCheckBox.Value == 0 && app.NotIndependentCheckBox.Value == 0
                errordlg('Choose if the varibles are independent or not.','Error');
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            delete(app.PairsofRandomVariablesUIFigure)  %close the app
            MainMenu; % open MainMenu window
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            delete(app.PairsofRandomVariablesUIFigure) %close the app
            PairsRandomVariables; % re-open it again
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PairsofRandomVariablesUIFigure = uifigure('Visible', 'off');
            app.PairsofRandomVariablesUIFigure.Name = 'Pairs of Random Variables';
            app.PairsofRandomVariablesUIFigure.Icon = 'UpcLogo.png';
            app.Panel1 = uipanel(app.PairsofRandomVariablesUIFigure);
            app.Panel1.Title = 'Joint PDF for a Gaussian pair of random variables';
            app.Panel2 = uipanel(app.PairsofRandomVariablesUIFigure);
            app.Panel2.Title = 'Aproximation of joint PDF for a Gaussian pair of random variables ';
            app.Panel3 = uipanel(app.PairsofRandomVariablesUIFigure);
            app.Panel3.Title = 'Regression Line ';
            app.xrangeEditFieldLabel = uilabel(app.PairsofRandomVariablesUIFigure);
            app.xrangeEditFieldLabel.Text = 'x range ';
            app.xrangeEditField = uieditfield(app.PairsofRandomVariablesUIFigure, 'numeric');
            app.xrangeEditField.Limits = [50 Inf];
            app.xrangeEditField.ValueDisplayFormat = '%.0f';
            app.xrangeEditField.Value = 50;
            app.RestartButton = uibutton(app.PairsofRandomVariablesUIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.yrangeEditFieldLabel = uilabel(app.PairsofRandomVariablesUIFigure);
            app.yrangeEditFieldLabel.Text = 'y range ';
            app.yrangeEditField = uieditfield(app.PairsofRandomVariablesUIFigure, 'numeric');
            app.yrangeEditField.Limits = [50 Inf];
            app.yrangeEditField.ValueDisplayFormat = '%.0f';
            app.yrangeEditField.Value = 50;
            app.mxEditFieldLabel = uilabel(app.PairsofRandomVariablesUIFigure);
            app.mxEditFieldLabel.Text = 'mx';
            app.mxEditField = uieditfield(app.PairsofRandomVariablesUIFigure, 'numeric');
            app.myEditFieldLabel = uilabel(app.PairsofRandomVariablesUIFigure);
            app.myEditFieldLabel.Text = 'my';
            app.myEditField = uieditfield(app.PairsofRandomVariablesUIFigure, 'numeric');
            app.sxEditFieldLabel = uilabel(app.PairsofRandomVariablesUIFigure);
            app.sxEditFieldLabel.Text = 'sx';
            app.sxEditField = uieditfield(app.PairsofRandomVariablesUIFigure, 'numeric');
            app.sxEditField.Value = 5;
            app.syEditFieldLabel = uilabel(app.PairsofRandomVariablesUIFigure);
            app.syEditFieldLabel.Text = 'sy';
            app.syEditField = uieditfield(app.PairsofRandomVariablesUIFigure, 'numeric');
            app.syEditField.Value = 6;
            app.rxyEditFieldLabel = uilabel(app.PairsofRandomVariablesUIFigure);
            app.rxyEditFieldLabel.Text = 'rxy';
            app.rxyEditField = uieditfield(app.PairsofRandomVariablesUIFigure, 'numeric');
            app.rxyEditField.Limits = [0.1 0.9];
            app.rxyEditField.Value = 0.2;
            app.PlotJointPDFMarginalsButton = uibutton(app.PairsofRandomVariablesUIFigure, 'push');
            app.PlotJointPDFMarginalsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotJointPDFMarginalsButtonPushed, true);
            app.PlotJointPDFMarginalsButton.Text = 'Plot Joint PDF & Marginals';
            app.RealizationsofXEditFieldLabel = uilabel(app.PairsofRandomVariablesUIFigure);
            app.RealizationsofXEditFieldLabel.Text = 'Realizations of X';
            app.RealizationsofXEditField = uieditfield(app.PairsofRandomVariablesUIFigure, 'numeric');
            app.RealizationsofXEditField.Limits = [5 Inf];
            app.RealizationsofXEditField.ValueDisplayFormat = '%.0f';
            app.RealizationsofXEditField.Value = 400;
            app.aEditFieldLabel = uilabel(app.PairsofRandomVariablesUIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.PairsofRandomVariablesUIFigure, 'numeric');
            app.aEditField.Value = 2;
            app.bEditFieldLabel = uilabel(app.PairsofRandomVariablesUIFigure);
            app.bEditFieldLabel.Text = 'b ';
            app.bEditField = uieditfield(app.PairsofRandomVariablesUIFigure, 'numeric');
            app.bEditField.Value = -2;
            app.PlotJointPDFButton_2 = uibutton(app.PairsofRandomVariablesUIFigure, 'push');
            app.PlotJointPDFButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotJointPDFButton_2Pushed, true);
            app.PlotJointPDFButton_2.Text = 'Plot Joint PDF';
            app.RealizationsofXYEditFieldLabel = uilabel(app.PairsofRandomVariablesUIFigure);
            app.RealizationsofXYEditFieldLabel.Text = 'Realizations of X & Y';
            app.RealizationsofXYEditField = uieditfield(app.PairsofRandomVariablesUIFigure, 'numeric');
            app.RealizationsofXYEditField.Limits = [5 Inf];
            app.RealizationsofXYEditField.ValueDisplayFormat = '%.0f';
            app.RealizationsofXYEditField.Value = 200;
            app.IndependentCheckBox = uicheckbox(app.PairsofRandomVariablesUIFigure);
            app.IndependentCheckBox.Text = 'Independent ';
            app.NotIndependentCheckBox = uicheckbox(app.PairsofRandomVariablesUIFigure);
            app.NotIndependentCheckBox.Text = 'Not Independent ';
            app.PlotRegressionLineButton = uibutton(app.PairsofRandomVariablesUIFigure, 'push');
            app.PlotRegressionLineButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRegressionLineButtonPushed, true);
            app.PlotRegressionLineButton.Text = 'Plot Regression Line';
            app.BackButton = uibutton(app.PairsofRandomVariablesUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back ';
            % Show the figure after all components are created
            app.PairsofRandomVariablesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PairsRandomVariables

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PairsofRandomVariablesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PairsofRandomVariablesUIFigure)
        end
    end
end