classdef ParameterEstimation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ParameterEstimationUIFigure  matlab.ui.Figure
        NoiseVarianceEditFieldLabel  matlab.ui.control.Label
        NoiseVarianceEditField       matlab.ui.control.NumericEditField
        ParamMeanEditFieldLabel      matlab.ui.control.Label
        ParamMeanEditField           matlab.ui.control.NumericEditField
        ParamVarianceEditFieldLabel  matlab.ui.control.Label
        ParamVarianceEditField       matlab.ui.control.NumericEditField
        ObservedArrayEditFieldLabel  matlab.ui.control.Label
        ObservedArrayEditField       matlab.ui.control.EditField
        minlimEditFieldLabel         matlab.ui.control.Label
        minlimEditField              matlab.ui.control.NumericEditField
        maxlimEditFieldLabel         matlab.ui.control.Label
        maxlimEditField              matlab.ui.control.NumericEditField
        th0EditFieldLabel            matlab.ui.control.Label
        th0EditField                 matlab.ui.control.NumericEditField
        lambdaEditFieldLabel         matlab.ui.control.Label
        lambdaEditField              matlab.ui.control.NumericEditField
        PlotsPanel                   matlab.ui.container.Panel
        ExpDistributionCheckBox      matlab.ui.control.CheckBox
        PlausabilityforonemeasurementLabel  matlab.ui.control.Label
        EnterParametersButton        matlab.ui.control.Button
        MeasurementNoEditFieldLabel  matlab.ui.control.Label
        MeasurementNoEditField       matlab.ui.control.NumericEditField
        ComputeButton1               matlab.ui.control.Button
        PlotButton1                  matlab.ui.control.Button
        PlausabilityforallobservedarrayLabel  matlab.ui.control.Label
        ComputeButton2               matlab.ui.control.Button
        PlotButton2                  matlab.ui.control.Button
        AprioriPDFmaxaprioriestimateLabel  matlab.ui.control.Label
        ComputeButton3               matlab.ui.control.Button
        PlotButton3                  matlab.ui.control.Button
        AposterioriPDFmaxaposterioriestimateLabel  matlab.ui.control.Label
        MaxplausibilityestimateEditField_3Label  matlab.ui.control.Label
        MaxplausibilityestimateEditField1  matlab.ui.control.EditField
        MaxplausibilityestimateEditField_4Label  matlab.ui.control.Label
        MaxplausibilityestimateEditField2  matlab.ui.control.EditField
        MaxaprioriestimateEditFieldLabel  matlab.ui.control.Label
        MaxaprioriestimateEditField  matlab.ui.control.EditField
        MaxaposterioriestimateEditFieldLabel  matlab.ui.control.Label
        MaxaposterioriestimateEditField  matlab.ui.control.EditField
        BackButton                   matlab.ui.control.Button
        RestartButton                matlab.ui.control.Button
        ComputeButton4               matlab.ui.control.Button
        PlotButton4                  matlab.ui.control.Button
        NEditFieldLabel              matlab.ui.control.Label
        NEditField                   matlab.ui.control.NumericEditField
        thetaEditFieldLabel          matlab.ui.control.Label
        thetaEditField               matlab.ui.control.NumericEditField
        PlotPanel                    matlab.ui.container.Panel
        PlotButton5                  matlab.ui.control.Button
        ComputedthetaEditFieldLabel  matlab.ui.control.Label
        ComputedthetaEditField       matlab.ui.control.EditField
    end

    
    properties (Access = public)
        s_noise % noise variance 
        mean_th % parameter mean
        s_th % parameter variance
        m % observed array 
        N % length of app.N
        min_lim % minimum limit for the possible params range 
        max_lim % maximum limit for the possible params range 
        th0
        lambda
        exponential % true if we have exponential distribution
        meas_no % the measurement for which the user wishes to obtain max. plausability estimate
        possible_vals % array containing all the possible values of the parameter 
        w_mn_th
        w_m_th
        w_th
        w_map
        t % tiled layout 
        t2 % second tiled layout 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterParametersButton
        function EnterParametersButtonPushed(app, event)
            % if the exponential distrubution checkbox is checked, set
            % exponential to true and enable the th0 and lambda EditFields
            if app.ExpDistributionCheckBox.Value == 1
                app.exponential=true;
                app.th0EditField.Enable = 'On';
                app.th0EditField.Editable = 'On';
                app.lambdaEditField.Enable = 'On';
                app.lambdaEditField.Editable = 'On';
            % if the exponential distrubution checkbox is not checked, set
            % exponential to false and disable the th0 and lambda EditFields
            else
                app.exponential=false;
                app.th0EditField.Enable = 'Off';
                app.th0EditField.Editable = 'Off';
                app.lambdaEditField.Enable = 'Off';
                app.lambdaEditField.Editable = 'Off';
            end
            
            % read the s_noise, mean_th, s_th, m, min_lim, max_lim, th0 and
            % lambda from EditFields and generate N and possible_vals
            app.s_noise=app.NoiseVarianceEditField.Value; 
            app.mean_th=app.ParamMeanEditField.Value; 
            app.s_th=app.ParamVarianceEditField.Value;
            app.m=app.ObservedArrayEditField.Value;
            app.m=split(app.m,",");
            app.m=str2double(app.m)';
            app.N=length(app.m);
            app.min_lim=app.minlimEditField.Value; 
            app.max_lim=app.maxlimEditField.Value;
            app.possible_vals=app.min_lim:0.1:app.max_lim; 
            app.th0=app.th0EditField.Value;
            app.lambda=app.lambdaEditField.Value;
            
            % Clear existing axes on panel and set new layout as 2x1
            % The axis will not appear yet. app.PlotPanel is the UI panel handle.
            app.t = tiledlayout(app.PlotsPanel, 2, 2);
        end

        % Button pushed function: ComputeButton1
        function ComputeButton1Pushed(app, event)
            % read the measurement number for which it is desired to obtain
            % the information
            app.meas_no=app.MeasurementNoEditField.Value;
            % compute the max. plausability estimate for the desired
            % measurement
            app.w_mn_th=1/(sqrt(2*pi*app.s_noise)).*exp(-((app.m(app.meas_no)-app.possible_vals).^2)./(2*app.s_noise));
            [~,pos]=max(app.w_mn_th);
            app.MaxplausibilityestimateEditField1.Value=string(app.possible_vals(pos));
        end

        % Button pushed function: PlotButton1
        function PlotButton1Pushed(app, event)
            ax1 = nexttile(app.t);
            % plot the plausability function for one measurement
            plot(ax1,app.possible_vals,app.w_mn_th,".k");
            [~,pos]=max(app.w_mn_th);
            plot_title="theta="+string(app.possible_vals(pos));
            title(ax1, plot_title);
            grid(ax1,'on');
        end

        % Button pushed function: ComputeButton2
        function ComputeButton2Pushed(app, event)
            % compute the max. plausability estimate for all measurements
            for i=1:length(app.possible_vals)
                rTh=sum((app.m-app.possible_vals(i)).*(app.m-app.possible_vals(i)));
                app.w_m_th(i)=1/((2*pi*app.s_noise)^4).*exp(-rTh/(2*app.s_noise));
            end

            [~, pos]=max(app.w_m_th);
            app.MaxplausibilityestimateEditField2.Value=string(app.possible_vals(pos));
        end

        % Button pushed function: PlotButton2
        function PlotButton2Pushed(app, event)
            % plot the plausability function for all measurements
            ax2 = nexttile(app.t);
            plot(ax2,app.possible_vals,app.w_m_th,"xk");
            [~, pos]=max(app.w_m_th);
            plot_title="theta="+string(app.possible_vals(pos));
            title(ax2, plot_title);
            grid(ax2,'on');
        end

        % Button pushed function: ComputeButton3
        function ComputeButton3Pushed(app, event)
            % compute the maximum a priori estimate 
            % in the non-exponential case
            if app.exponential==false
                app.w_th=1/((2*pi*app.s_th)^4).*exp(-((app.possible_vals-app.mean_th).^2)/(2*app.s_th));
            % and the exponentail case
            elseif app.exponential==true
                app.w_th=zeros(size(app.possible_vals));
                app.w_th(app.possible_vals ...
                    >=app.th0)=app.lambda*exp(-app.lambda*(app.possible_vals(app.possible_vals>=app.th0)-app.th0));
            end
            [~,pos]=max(app.w_th);
            app.MaxaprioriestimateEditField.Value=string(app.possible_vals(pos));
        end

        % Button pushed function: PlotButton3
        function PlotButton3Pushed(app, event)
            % plot a priori PDF
            ax3 = nexttile(app.t);
            plot(ax3,app.possible_vals,app.w_th,"*k");
            [~, pos]=max(app.w_th);
            plot_title="theta="+string(app.possible_vals(pos));
            title(ax3,plot_title); 
            grid(ax3,"on");
        end

        % Button pushed function: ComputeButton4
        function ComputeButton4Pushed(app, event)
            % compute the maximum a posteriori estimate 
            for i=1:length(app.possible_vals)
                rTh=sum((app.m-app.possible_vals(i)).*(app.m-app.possible_vals(i)));
                app.w_map(i)=(-rTh/(2*app.s_noise)-((app.possible_vals(i)-app.mean_th).^2)/(2*app.s_th));
            end
            [~,pos]=max(app.w_map);
            app.MaxaposterioriestimateEditField.Value=string(app.possible_vals(pos));
        end

        % Button pushed function: PlotButton4
        function PlotButton4Pushed(app, event)
            % plot a posteriori PDF
            ax4 = nexttile(app.t);
            plot(ax4,app.possible_vals,app.w_map,"ok");
            [~, pos]=max(app.w_map);
            plot_title="theta="+string(app.possible_vals(pos));
            title(ax4,plot_title); 
            grid(ax4,"on");
        end

        % Button pushed function: PlotButton5
        function PlotButton5Pushed(app, event)
            num=app.NEditField.Value; 
            theta=app.thetaEditField.Value; % transmitted parameter value 
            t_mom=pi/num:pi/num:pi; % time moments
            t_signal=theta*sin(t_mom); % transmitted signal
            noise=randn(1,num); % noise
            r_signal=t_signal+noise; % received signal 
            theta_c=sum(r_signal)/sum(sin(t_mom)); % calculated theta
            app.ComputedthetaEditField.Value=string(theta_c);
            
            % Clear existing axes on panel and set new layout as 2x1
            % The axis will not appear yet. app.PlotPanel is the UI panel handle.
            app.t2= tiledlayout(app.PlotPanel, 1, 1);
            ax1 = nexttile(app.t2);
            plot(ax1,t_mom,t_signal,":k");
            hold(ax1,'on'); 
            plot(ax1,t_mom,r_signal,"Color",'#7E2F8E'); 
            hold(ax1,'on'); 
            plot(ax1,t_mom,r_signal,".k"); 
            legend(ax1, 'transmitted signal', 'received signal', 'Location','best'); 
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            delete(app.ParameterEstimationUIFigure) %close the app
            ParameterEstimation; %re-open it again
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            delete(app.ParameterEstimationUIFigure) %close the app
            MainMenu; % open MainMenu window
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ParameterEstimationUIFigure = uifigure('Visible', 'off');
            app.ParameterEstimationUIFigure.Name = 'Parameter Estimation';
            app.ParameterEstimationUIFigure.Icon = 'UpcLogo.png';
            app.NoiseVarianceEditFieldLabel = uilabel(app.ParameterEstimationUIFigure);
            app.NoiseVarianceEditFieldLabel.Text = 'Noise Variance';
            app.NoiseVarianceEditField = uieditfield(app.ParameterEstimationUIFigure, 'numeric');
            app.NoiseVarianceEditField.Value = 0.1;
            app.ParamMeanEditFieldLabel = uilabel(app.ParameterEstimationUIFigure);
            app.ParamMeanEditFieldLabel.Text = 'Param Mean';
            app.ParamMeanEditField = uieditfield(app.ParameterEstimationUIFigure, 'numeric');
            app.ParamMeanEditField.Value = 11;
            app.ParamVarianceEditFieldLabel = uilabel(app.ParameterEstimationUIFigure);
            app.ParamVarianceEditFieldLabel.Text = 'Param Variance';
            app.ParamVarianceEditField = uieditfield(app.ParameterEstimationUIFigure, 'numeric');
            app.ParamVarianceEditField.Value = 1;
            app.ObservedArrayEditFieldLabel = uilabel(app.ParameterEstimationUIFigure);
            app.ObservedArrayEditFieldLabel.Text = 'Observed Array';
            app.ObservedArrayEditField = uieditfield(app.ParameterEstimationUIFigure, 'text');
            app.ObservedArrayEditField.Value = '9.1,10.5,11.2,10.2';
            app.minlimEditFieldLabel = uilabel(app.ParameterEstimationUIFigure);
            app.minlimEditFieldLabel.Text = 'min lim';
            app.minlimEditField = uieditfield(app.ParameterEstimationUIFigure, 'numeric');
            app.minlimEditField.Value = 8;
            app.maxlimEditFieldLabel = uilabel(app.ParameterEstimationUIFigure);
            app.maxlimEditFieldLabel.Text = 'max lim';
            app.maxlimEditField = uieditfield(app.ParameterEstimationUIFigure, 'numeric');
            app.maxlimEditField.Value = 13;
            app.th0EditFieldLabel = uilabel(app.ParameterEstimationUIFigure);
            app.th0EditFieldLabel.Text = 'th0';
            app.th0EditField = uieditfield(app.ParameterEstimationUIFigure, 'numeric');
            app.th0EditField.Value = 9;
            app.lambdaEditFieldLabel = uilabel(app.ParameterEstimationUIFigure);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.ParameterEstimationUIFigure, 'numeric');
            app.lambdaEditField.Value = 0.5;
            app.PlotsPanel = uipanel(app.ParameterEstimationUIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.ExpDistributionCheckBox = uicheckbox(app.ParameterEstimationUIFigure);
            app.ExpDistributionCheckBox.Text = 'Exp Distribution';
            app.PlausabilityforonemeasurementLabel = uilabel(app.ParameterEstimationUIFigure);
            app.PlausabilityforonemeasurementLabel.Text = 'Plausability for one measurement ';
            app.EnterParametersButton = uibutton(app.ParameterEstimationUIFigure, 'push');
            app.EnterParametersButton.ButtonPushedFcn = createCallbackFcn(app, @EnterParametersButtonPushed, true);
            app.EnterParametersButton.Text = 'Enter Parameters';
            app.MeasurementNoEditFieldLabel = uilabel(app.ParameterEstimationUIFigure);
            app.MeasurementNoEditFieldLabel.Text = 'Measurement No.';
            app.MeasurementNoEditField = uieditfield(app.ParameterEstimationUIFigure, 'numeric');
            app.MeasurementNoEditField.Value = 1;
            app.ComputeButton1 = uibutton(app.ParameterEstimationUIFigure, 'push');
            app.ComputeButton1.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton1Pushed, true);
            app.ComputeButton1.Text = 'Compute';
            app.PlotButton1 = uibutton(app.ParameterEstimationUIFigure, 'push');
            app.PlotButton1.ButtonPushedFcn = createCallbackFcn(app, @PlotButton1Pushed, true);
            app.PlotButton1.Text = 'Plot';
            app.PlausabilityforallobservedarrayLabel = uilabel(app.ParameterEstimationUIFigure);
            app.PlausabilityforallobservedarrayLabel.Text = 'Plausability for all observed array ';
            app.ComputeButton2 = uibutton(app.ParameterEstimationUIFigure, 'push');
            app.ComputeButton2.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton2Pushed, true);
            app.ComputeButton2.Text = 'Compute';
            app.PlotButton2 = uibutton(app.ParameterEstimationUIFigure, 'push');
            app.PlotButton2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton2Pushed, true);
            app.PlotButton2.Text = 'Plot';
            app.AprioriPDFmaxaprioriestimateLabel = uilabel(app.ParameterEstimationUIFigure);
            app.AprioriPDFmaxaprioriestimateLabel.Text = 'A priori PDF & max. a priori estimate  ';
            app.ComputeButton3 = uibutton(app.ParameterEstimationUIFigure, 'push');
            app.ComputeButton3.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton3Pushed, true);
            app.ComputeButton3.Text = 'Compute';
            app.PlotButton3 = uibutton(app.ParameterEstimationUIFigure, 'push');
            app.PlotButton3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton3Pushed, true);
            app.PlotButton3.Text = 'Plot';
            app.AposterioriPDFmaxaposterioriestimateLabel = uilabel(app.ParameterEstimationUIFigure);
            app.AposterioriPDFmaxaposterioriestimateLabel.Text = 'A posteriori PDF & max. a posteriori estimate  ';
            app.MaxplausibilityestimateEditField_3Label = uilabel(app.ParameterEstimationUIFigure);
            app.MaxplausibilityestimateEditField_3Label.Text = 'Max plausibility estimate';
            app.MaxplausibilityestimateEditField1 = uieditfield(app.ParameterEstimationUIFigure, 'text');
            app.MaxplausibilityestimateEditField1.Editable = 'off';
            app.MaxplausibilityestimateEditField_4Label = uilabel(app.ParameterEstimationUIFigure);
            app.MaxplausibilityestimateEditField_4Label.Text = 'Max plausibility estimate';
            app.MaxplausibilityestimateEditField2 = uieditfield(app.ParameterEstimationUIFigure, 'text');
            app.MaxplausibilityestimateEditField2.Editable = 'off';
            app.MaxaprioriestimateEditFieldLabel = uilabel(app.ParameterEstimationUIFigure);
            app.MaxaprioriestimateEditFieldLabel.Text = 'Max. a priori estimate';
            app.MaxaprioriestimateEditField = uieditfield(app.ParameterEstimationUIFigure, 'text');
            app.MaxaprioriestimateEditField.Editable = 'off';
            app.MaxaposterioriestimateEditFieldLabel = uilabel(app.ParameterEstimationUIFigure);
            app.MaxaposterioriestimateEditFieldLabel.Text = 'Max. a posteriori estimate';
            app.MaxaposterioriestimateEditField = uieditfield(app.ParameterEstimationUIFigure, 'text');
            app.MaxaposterioriestimateEditField.Editable = 'off';
            app.BackButton = uibutton(app.ParameterEstimationUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.RestartButton = uibutton(app.ParameterEstimationUIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.ComputeButton4 = uibutton(app.ParameterEstimationUIFigure, 'push');
            app.ComputeButton4.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton4Pushed, true);
            app.ComputeButton4.Text = 'Compute';
            app.PlotButton4 = uibutton(app.ParameterEstimationUIFigure, 'push');
            app.PlotButton4.ButtonPushedFcn = createCallbackFcn(app, @PlotButton4Pushed, true);
            app.PlotButton4.Text = 'Plot';
            app.NEditFieldLabel = uilabel(app.ParameterEstimationUIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.ParameterEstimationUIFigure, 'numeric');
            app.NEditField.Value = 5;
            app.thetaEditFieldLabel = uilabel(app.ParameterEstimationUIFigure);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.ParameterEstimationUIFigure, 'numeric');
            app.thetaEditField.Value = 2;
            app.PlotPanel = uipanel(app.ParameterEstimationUIFigure);
            app.PlotPanel.Title = 'Plot';
            app.PlotButton5 = uibutton(app.ParameterEstimationUIFigure, 'push');
            app.PlotButton5.ButtonPushedFcn = createCallbackFcn(app, @PlotButton5Pushed, true);
            app.PlotButton5.Text = 'Plot';
            app.ComputedthetaEditFieldLabel = uilabel(app.ParameterEstimationUIFigure);
            app.ComputedthetaEditFieldLabel.Text = 'Computed theta';
            app.ComputedthetaEditField = uieditfield(app.ParameterEstimationUIFigure, 'text');
            app.ComputedthetaEditField.Editable = 'off';
            % Show the figure after all components are created
            app.ParameterEstimationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParameterEstimation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParameterEstimationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParameterEstimationUIFigure)
        end
    end
end