classdef RandomSignals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RandomSignalsUIFigure          matlab.ui.Figure
        NumRealizationsEditFieldLabel  matlab.ui.control.Label
        NumRealizationsEditField       matlab.ui.control.NumericEditField
        trangeEditFieldLabel           matlab.ui.control.Label
        trangeEditField                matlab.ui.control.NumericEditField
        AngularFrequencywEditFieldLabel  matlab.ui.control.Label
        AngularFrequencywEditField     matlab.ui.control.NumericEditField
        PlotRandomSignalButton         matlab.ui.control.Button
        PlotMeanButton                 matlab.ui.control.Button
        PlotDispersionButton           matlab.ui.control.Button
        PlotAutoCorrelationButton      matlab.ui.control.Button
        Signal1CheckBox                matlab.ui.control.CheckBox
        Signal2CheckBox                matlab.ui.control.CheckBox
        Panel1                         matlab.ui.container.Panel
        Panel2                         matlab.ui.container.Panel
        TextArea                       matlab.ui.control.TextArea
        ComputePSDButton               matlab.ui.control.Button
        PlotPSDButton                  matlab.ui.control.Button
        RestartButton                  matlab.ui.control.Button
        BackButton                     matlab.ui.control.Button
        AEditField_2Label              matlab.ui.control.Label
        AEditField1                    matlab.ui.control.NumericEditField
        SelectSignalButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        N  % Num. realizations 
        t_lim  % t will be -t_lim:t_lim/10:t_lim
        t 
        w  % angular frequency
        A  % constant
        x  % matrix containing the random signal;
        t1 % tiled layout for random signal panel 
        t2 % tiled layout for PSD panel
        select_ok % true is signal was selected correctly, else, false
        PSD % power spectral density of the signal 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectSignalButton
        function SelectSignalButtonPushed(app, event)
            % if signal is of the first type, disable A edit field and set
            % select_ok to true
            if app.Signal1CheckBox.Value == 1 && app.Signal2CheckBox.Value == 0
                app.AEditField1.Enable = 'Off';
                app.AEditField1.Editable = 'Off';
                app.select_ok = true;
            % if signal is of the first type, enable A edit field and set
            % select_ok to true
            elseif app.Signal1CheckBox.Value == 0 && app.Signal2CheckBox.Value == 1
                app.AEditField1.Enable = 'On';
                app.AEditField1.Editable = 'On';
                app.select_ok = true;
            % if both signal types are selected, set select_ok=false and
            % throw error
            elseif app.Signal1CheckBox.Value == 1 && app.Signal2CheckBox.Value == 1
                errordlg('The sigal can only be of one type at a time.','Error');
                app.select_ok = false;
            % if none of the signal types are selected, set select_ok=false and
            % throw error
            elseif app.Signal1CheckBox.Value == 0 && app.Signal2CheckBox.Value == 0
                errordlg('Choose the type of signal.','Error');
                app.select_ok = false; 
            end
        end

        % Button pushed function: PlotRandomSignalButton
        function PlotRandomSignalButtonPushed(app, event)
            % if select_ok is true, read N, t_lim, w from EditFields and
            % generate t
            if app.select_ok == true
                app.N = app.NumRealizationsEditField.Value;
                app.t_lim = app.trangeEditField.Value;
                app.t=-app.t_lim*pi:app.t_lim/10:app.t_lim*pi;
                app.w = app.AngularFrequencywEditField.Value;
                app.t1 = tiledlayout(app.Panel1, 2, 2);
                % Access first subplot-tile for plotting
                ax1 = nexttile(app.t1);
                % if signal type 1 is selected, plot it 
                if app.Signal1CheckBox.Value == 1 && app.Signal2CheckBox.Value == 0
                    for i=1:app.N
                        rand_sig(i,:)=randn(1,1)*sin(app.w*app.t);
                        plot(ax1,app.t,rand_sig(i,:));
                        hold(ax1,'on');
                        title(ax1,'random signal');
                        if i == app.N
                            app.x = rand_sig;
                        end
                    end
                % if signal type 2 is selected, plot it 
                elseif app.Signal1CheckBox.Value == 0 && app.Signal2CheckBox.Value == 1
                    app.A = app.AEditField1.Value;
                    P = 2*pi*(rand(1,app.N)-0.5);
                    for i=1:app.N
                        rand_sig(i,:)=app.A*sin(app.w*app.t+P(i));
                        plot(ax1, app.t,rand_sig(i,:)); 
                        hold(ax1,'on');
                        title(ax1,'random signal');
                        if i==app.N
                            app.x = rand_sig;
                        end
                    end
                % if select_ok is false, throw error 
                elseif app.select_ok == false
                    errordlg('Make sure you selected the signal correctly.','Error');
                end
            end
        end

        % Button pushed function: PlotMeanButton
        function PlotMeanButtonPushed(app, event)
            % plot the mean
            m=mean(app.x,1); 
            ax2=nexttile(app.t1);
            % plot the mean
            plot(ax2,app.t,m,"Color",'#77AC30'); 
            title(ax2,'mean');
        end

        % Button pushed function: PlotDispersionButton
        function PlotDispersionButtonPushed(app, event)
            % plot the dispersion
            s=std(app.x,0,1); 
            ax3=nexttile(app.t1);
            plot(ax3,app.t,s,"Color", '#77AC30'); 
            title(ax3, 'dispersion');
        end

        % Button pushed function: PlotAutoCorrelationButton
        function PlotAutoCorrelationButtonPushed(app, event)
            % compute auto-correlation
            for i=1:length(app.t)
                for j=1:length(app.t)
                    R(i,j)=mean(app.x(:,i).*app.x(:,j));
                end
            end
            % plot the auto-correlation function
            ax4=nexttile(app.t1);
            surf(ax4,app.t,app.t,R); 
            title(ax4, 'Auto-Correlation Function');
        end

        % Button pushed function: ComputePSDButton
        function ComputePSDButtonPushed(app, event)
            % compute the power spectral density
            app.PSD = abs(fft(app.x,[],2)); 
            app.PSD = abs(mean(app.PSD.*app.PSD)/(10*pi));
            % add it to the text area
            app.TextArea.Value = mat2str(app.PSD);
        end

        % Button pushed function: PlotPSDButton
        function PlotPSDButtonPushed(app, event)
            % plot the PSD
            x_vals=1:length(app.PSD);
            y_vals=app.PSD;
            app.t2 = tiledlayout(app.Panel2, 1, 1);
            % Access first subplot-tile for plotting
            ax2 = nexttile(app.t2);
            plot(ax2,x_vals,y_vals, "Color", '#7E2F8E');
            title(ax2,'Power Spectral Density')
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            delete(app.RandomSignalsUIFigure);
            RandomSignals;
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            delete(app.RandomSignalsUIFigure);
            MainMenu;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RandomSignalsUIFigure = uifigure('Visible', 'off');
            app.RandomSignalsUIFigure.Name = 'Random Signals';
            app.RandomSignalsUIFigure.Icon = 'UpcLogo.png';
            app.NumRealizationsEditFieldLabel = uilabel(app.RandomSignalsUIFigure);
            app.NumRealizationsEditFieldLabel.Text = 'Num. Realizations ';
            app.NumRealizationsEditField = uieditfield(app.RandomSignalsUIFigure, 'numeric');
            app.NumRealizationsEditField.Limits = [10 Inf];
            app.NumRealizationsEditField.ValueDisplayFormat = '%.0f';
            app.NumRealizationsEditField.Value = 50;
            app.trangeEditFieldLabel = uilabel(app.RandomSignalsUIFigure);
            app.trangeEditFieldLabel.Text = 't range ';
            app.trangeEditField = uieditfield(app.RandomSignalsUIFigure, 'numeric');
            app.trangeEditField.Limits = [0.1 Inf];
            app.trangeEditField.Value = 5;
            app.AngularFrequencywEditFieldLabel = uilabel(app.RandomSignalsUIFigure);
            app.AngularFrequencywEditFieldLabel.Text = 'Angular Frequency (w)';
            app.AngularFrequencywEditField = uieditfield(app.RandomSignalsUIFigure, 'numeric');
            app.AngularFrequencywEditField.Value = 1;
            app.PlotRandomSignalButton = uibutton(app.RandomSignalsUIFigure, 'push');
            app.PlotRandomSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRandomSignalButtonPushed, true);
            app.PlotRandomSignalButton.Text = 'Plot Random Signal';
            app.PlotMeanButton = uibutton(app.RandomSignalsUIFigure, 'push');
            app.PlotMeanButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMeanButtonPushed, true);
            app.PlotMeanButton.Text = 'Plot Mean';
            app.PlotDispersionButton = uibutton(app.RandomSignalsUIFigure, 'push');
            app.PlotDispersionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDispersionButtonPushed, true);
            app.PlotDispersionButton.Text = 'Plot Dispersion';
            app.PlotAutoCorrelationButton = uibutton(app.RandomSignalsUIFigure, 'push');
            app.PlotAutoCorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAutoCorrelationButtonPushed, true);
            app.PlotAutoCorrelationButton.Text = 'Plot Auto-Correlation';
            app.Signal1CheckBox = uicheckbox(app.RandomSignalsUIFigure);
            app.Signal1CheckBox.Text = 'A*sin(wt)  signal';
            app.Signal2CheckBox = uicheckbox(app.RandomSignalsUIFigure);
            app.Signal2CheckBox.Text = 'A*sin(wt + P) signal';
            app.Panel1 = uipanel(app.RandomSignalsUIFigure);
            app.Panel1.Title = 'Random Signal';
            app.Panel2 = uipanel(app.RandomSignalsUIFigure);
            app.Panel2.Title = 'Power Spectral Density';
            app.TextArea = uitextarea(app.RandomSignalsUIFigure);
            app.ComputePSDButton = uibutton(app.RandomSignalsUIFigure, 'push');
            app.ComputePSDButton.ButtonPushedFcn = createCallbackFcn(app, @ComputePSDButtonPushed, true);
            app.ComputePSDButton.Text = 'Compute PSD';
            app.PlotPSDButton = uibutton(app.RandomSignalsUIFigure, 'push');
            app.PlotPSDButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPSDButtonPushed, true);
            app.PlotPSDButton.Text = 'Plot PSD';
            app.RestartButton = uibutton(app.RandomSignalsUIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.BackButton = uibutton(app.RandomSignalsUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.AEditField_2Label = uilabel(app.RandomSignalsUIFigure);
            app.AEditField_2Label.Text = 'A';
            app.AEditField1 = uieditfield(app.RandomSignalsUIFigure, 'numeric');
            app.AEditField1.Value = 1;
            app.SelectSignalButton = uibutton(app.RandomSignalsUIFigure, 'push');
            app.SelectSignalButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSignalButtonPushed, true);
            app.SelectSignalButton.Text = 'Select Signal';
            % Show the figure after all components are created
            app.RandomSignalsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RandomSignals

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RandomSignalsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RandomSignalsUIFigure)
        end
    end
end