classdef RandomVariables < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RandomVariablesUIFigure        matlab.ui.Figure
        TextArea1                      matlab.ui.control.TextArea
        SeeXButton                     matlab.ui.control.Button
        RealizationsofXEditFieldLabel  matlab.ui.control.Label
        RealizationsofXEditField       matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        TextArea2                      matlab.ui.control.TextArea
        ExponentialCheckBox            matlab.ui.control.CheckBox
        ComputeButtonX                 matlab.ui.control.Button
        ComputeButtonY                 matlab.ui.control.Button
        PlotRVsButton                  matlab.ui.control.Button
        PlotHistogramButton            matlab.ui.control.Button
        PlotCumulativeHistogramButton  matlab.ui.control.Button
        MeanXEditFieldLabel            matlab.ui.control.Label
        MeanXEditField                 matlab.ui.control.EditField
        DispersionXEditFieldLabel      matlab.ui.control.Label
        DispersionXEditField           matlab.ui.control.EditField
        MeanYEditFieldLabel            matlab.ui.control.Label
        MeanYEditField                 matlab.ui.control.EditField
        DispersionYEditFieldLabel      matlab.ui.control.Label
        DispersionYEditField           matlab.ui.control.EditField
        EnterButton2                   matlab.ui.control.Button
        lambdaEditFieldLabel           matlab.ui.control.Label
        lambdaEditField                matlab.ui.control.NumericEditField
        RestartButton                  matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        BackButton                     matlab.ui.control.Button
    end

    
    properties (Access = public)
        % array of uniformly distributed random variables 
        X  
        Y
        % histogram
        h_X 
        h_Y  
        absX
        absY 
        % cumulaive histrogram
        H_X
        H_Y
        Exponential = 0;
        % tiled layout 
        t 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SeeXButton
        function SeeXButtonPushed(app, event)
            % store the num. of realizations of X entered by user
            NumX =  app.RealizationsofXEditField.Value;
            %  array of uniformly distributed NumX random variables
            app.X=rand(1, NumX);
            % add array of uniformly distributed NumX random variables to
            % text area
            app.TextArea1.Value = mat2str(app.X);
            % if the "Exponential" checkbox is checked, the a and b
            % EditFields are turned off and the lambda EditField is turned
            % on. 
            % "Exponential" means that the array of rv Y is generated
            % exponentially: Y=(-1/lambda).*log(1-X)
            if app.ExponentialCheckBox.Value
                app.aEditField.Enable = 'Off';
                app.aEditField.Editable = 'Off';
                app.bEditField.Enable = 'Off';
                app.bEditField.Editable = 'Off';
                app.lambdaEditField.Enable = 'On';
                app.lambdaEditField.Editable = 'On';
                app.Exponential = true;
            % the array of rv Y is generated lineraly: Y = a*X+b;
            else
                app.lambdaEditField.Enable = 'Off';
                app.lambdaEditField.Editable = 'Off';
                app.aEditField.Enable = 'On';
                app.aEditField.Editable = 'On';
                app.bEditField.Enable = 'On';
                app.bEditField.Editable = 'On';
                app.Exponential = false;
            end
        end

        % Button pushed function: ComputeButtonX
        function ComputeButtonXPushed(app, event)
            m_X=mean(app.X); % mean of X
            app.MeanXEditField.Value = num2str(m_X);
            s_X=std(app.X); %dispersion of X
            app.DispersionXEditField.Value = num2str(s_X);
        end

        % Button pushed function: EnterButton2
        function EnterButton2Pushed(app, event)
            if app.Exponential == true
                lambda = app.lambdaEditField.Value;
                app.Y=(-1/lambda).*log(1-app.X); % exponential equation
                app.TextArea2.Value = mat2str(app.Y); % add Y to text area
            else 
                a = app.aEditField.Value;
                b = app.bEditField.Value;
                app.Y = a*app.X+b; % linear equation
                app.TextArea2.Value = mat2str(app.Y); % add Y to text area
            end
            % histogram
            [app.h_X,app.absX]=hist(app.X,20); 
            [app.h_Y,app.absY]=hist(app.Y,20);
            % cumulative histogram
            app.H_X=cumsum(app.h_X);
            app.H_Y=cumsum(app.h_Y);
        end

        % Button pushed function: ComputeButtonY
        function ComputeButtonYPushed(app, event)
            m_Y=mean(app.Y); % mean of Y
            app.MeanYEditField.Value = num2str(m_Y);
            s_Y=std(app.Y); %dispersion of Y
            app.DispersionYEditField.Value = num2str(s_Y);
            % Clear existing axes on panel and set new layout as 6x1
            % The axis will not appear yet. app.Panel is the UI panel handle.
            app.t = tiledlayout(app.Panel, 6, 1);
        end

        % Button pushed function: PlotRVsButton
        function PlotRVsButtonPushed(app, event)
            % Access first subplot-tile for plotting
            ax1 = nexttile(app.t);
            % Plot X
            plot(ax1, app.X, "Color",'#7E2F8E')
            title(ax1,'X')
            % Access next subplot-tile for plotting
            ax2 = nexttile(app.t);
            % Plot Y
            plot(ax2, app.Y, "Color", '#77AC30')
            title(ax2,'Y')
        end

        % Button pushed function: PlotHistogramButton
        function PlotHistogramButtonPushed(app, event)
            % Plot X Histogram
            ax3 = nexttile(app.t);
            bar(ax3, app.absX, app.h_X, "FaceColor", '#7E2F8E')
            title(ax3, "X - Histogram")
            % Plot Y Histogram
            ax4 = nexttile(app.t);
            bar(ax4, app.absY, app.h_Y, "FaceColor", '#77AC30')
            title(ax4, "Y - Histogram")
        end

        % Button pushed function: PlotCumulativeHistogramButton
        function PlotCumulativeHistogramButtonPushed(app, event)
            % Plot X  Cumulative Histogram
            ax5 =  nexttile(app.t);
            bar(ax5, app.absX, app.H_X, "FaceColor", '#7E2F8E')
            title(ax5, "X - Cumulative Histogram")
            % Plot Y  Cumulative Histogram
            ax6 =  nexttile(app.t);
            bar(ax6, app.absY, app.H_Y, "FaceColor", '#77AC30')
            title(ax6, "Y - Cumulative Histogram")
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            delete(app.RandomVariablesUIFigure); %close the app
            RandomVariables; %re-open it again
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            delete(app.RandomVariablesUIFigure) %close the app
            MainMenu; % open MainMenu window
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RandomVariablesUIFigure = uifigure('Visible', 'off');
            app.RandomVariablesUIFigure.Name = 'Random Variables';
            app.RandomVariablesUIFigure.Icon = 'UpcLogo.png';
            app.TextArea1 = uitextarea(app.RandomVariablesUIFigure);
            app.SeeXButton = uibutton(app.RandomVariablesUIFigure, 'push');
            app.SeeXButton.ButtonPushedFcn = createCallbackFcn(app, @SeeXButtonPushed, true);
            app.SeeXButton.Text = 'See X';
            app.RealizationsofXEditFieldLabel = uilabel(app.RandomVariablesUIFigure);
            app.RealizationsofXEditFieldLabel.Text = 'Realizations of X';
            app.RealizationsofXEditField = uieditfield(app.RandomVariablesUIFigure, 'numeric');
            app.RealizationsofXEditField.Limits = [2 Inf];
            app.RealizationsofXEditField.Value = 100;
            app.aEditFieldLabel = uilabel(app.RandomVariablesUIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.RandomVariablesUIFigure, 'numeric');
            app.aEditField.Value = 1;
            app.bEditFieldLabel = uilabel(app.RandomVariablesUIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.RandomVariablesUIFigure, 'numeric');
            app.bEditField.Value = 2;
            app.TextArea2 = uitextarea(app.RandomVariablesUIFigure);
            app.ExponentialCheckBox = uicheckbox(app.RandomVariablesUIFigure);
            app.ExponentialCheckBox.Text = 'Exponential';
            app.ComputeButtonX = uibutton(app.RandomVariablesUIFigure, 'push');
            app.ComputeButtonX.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonXPushed, true);
            app.ComputeButtonX.Text = 'Compute ';
            app.ComputeButtonY = uibutton(app.RandomVariablesUIFigure, 'push');
            app.ComputeButtonY.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonYPushed, true);
            app.ComputeButtonY.Text = 'Compute';
            app.PlotRVsButton = uibutton(app.RandomVariablesUIFigure, 'push');
            app.PlotRVsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRVsButtonPushed, true);
            app.PlotRVsButton.Text = 'Plot RVs';
            app.PlotHistogramButton = uibutton(app.RandomVariablesUIFigure, 'push');
            app.PlotHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHistogramButtonPushed, true);
            app.PlotHistogramButton.Text = 'Plot Histogram';
            app.PlotCumulativeHistogramButton = uibutton(app.RandomVariablesUIFigure, 'push');
            app.PlotCumulativeHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCumulativeHistogramButtonPushed, true);
            app.PlotCumulativeHistogramButton.Text = 'Plot Cumulative Histogram';
            app.MeanXEditFieldLabel = uilabel(app.RandomVariablesUIFigure);
            app.MeanXEditFieldLabel.Text = 'Mean X';
            app.MeanXEditField = uieditfield(app.RandomVariablesUIFigure, 'text');
            app.MeanXEditField.Editable = 'off';
            app.DispersionXEditFieldLabel = uilabel(app.RandomVariablesUIFigure);
            app.DispersionXEditFieldLabel.Text = 'Dispersion X';
            app.DispersionXEditField = uieditfield(app.RandomVariablesUIFigure, 'text');
            app.DispersionXEditField.Editable = 'off';
            app.MeanYEditFieldLabel = uilabel(app.RandomVariablesUIFigure);
            app.MeanYEditFieldLabel.Text = 'Mean Y';
            app.MeanYEditField = uieditfield(app.RandomVariablesUIFigure, 'text');
            app.MeanYEditField.Editable = 'off';
            app.DispersionYEditFieldLabel = uilabel(app.RandomVariablesUIFigure);
            app.DispersionYEditFieldLabel.Text = 'Dispersion Y';
            app.DispersionYEditField = uieditfield(app.RandomVariablesUIFigure, 'text');
            app.DispersionYEditField.Editable = 'off';
            app.EnterButton2 = uibutton(app.RandomVariablesUIFigure, 'push');
            app.EnterButton2.ButtonPushedFcn = createCallbackFcn(app, @EnterButton2Pushed, true);
            app.EnterButton2.Text = 'See Y';
            app.lambdaEditFieldLabel = uilabel(app.RandomVariablesUIFigure);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.RandomVariablesUIFigure, 'numeric');
            app.lambdaEditField.Value = 2;
            app.RestartButton = uibutton(app.RandomVariablesUIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.Panel = uipanel(app.RandomVariablesUIFigure);
            app.Panel.Title = 'Plots Panel';
            app.BackButton = uibutton(app.RandomVariablesUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.RandomVariablesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RandomVariables

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RandomVariablesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RandomVariablesUIFigure)
        end
    end
end