classdef StatisticalDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StatisticalDetectionUIFigure   matlab.ui.Figure
        MeanEditFieldLabel             matlab.ui.control.Label
        MeanEditField                  matlab.ui.control.NumericEditField
        SigmaEditFieldLabel            matlab.ui.control.Label
        SigmaEditField                 matlab.ui.control.NumericEditField
        Panel                          matlab.ui.container.Panel
        PlotButton                     matlab.ui.control.Button
        NumSamplingPointsEditFieldLabel  matlab.ui.control.Label
        NumSamplingPointsEditField     matlab.ui.control.NumericEditField
        NoiseParametersLabel           matlab.ui.control.Label
        ReceivedSignalParametersLabel  matlab.ui.control.Label
        CalculateReceivedSignalParametersButton  matlab.ui.control.Button
        EnterParametersButton          matlab.ui.control.Button
        SymbolsTypeLabel               matlab.ui.control.Label
        type1CheckBox                  matlab.ui.control.CheckBox
        type2CheckBox                  matlab.ui.control.CheckBox
        Meanr0Label                    matlab.ui.control.Label
        Meanr0EditField                matlab.ui.control.EditField
        Sigmar0Label                   matlab.ui.control.Label
        Sigmar0EditField               matlab.ui.control.EditField
        c00EditFieldLabel              matlab.ui.control.Label
        c00EditField1                  matlab.ui.control.NumericEditField
        c11EditFieldLabel              matlab.ui.control.Label
        c11EditField1                  matlab.ui.control.NumericEditField
        c01EditFieldLabel              matlab.ui.control.Label
        c01EditField1                  matlab.ui.control.NumericEditField
        c10EditField_2Label            matlab.ui.control.Label
        c10EditField1                  matlab.ui.control.NumericEditField
        ComputeButton1                 matlab.ui.control.Button
        SigmaNoiseEditFieldLabel       matlab.ui.control.Label
        SigmaNoiseEditField1           matlab.ui.control.NumericEditField
        Decisionfors00s11Label         matlab.ui.control.Label
        Decisionfors0sints1costLabel   matlab.ui.control.Label
        c00EditField_2Label            matlab.ui.control.Label
        c00EditField2                  matlab.ui.control.NumericEditField
        c11EditField_2Label            matlab.ui.control.Label
        c11EditField2                  matlab.ui.control.NumericEditField
        c01EditField_2Label            matlab.ui.control.Label
        c01EditField2                  matlab.ui.control.NumericEditField
        c10EditField_3Label            matlab.ui.control.Label
        c10EditField2                  matlab.ui.control.NumericEditField
        MeanNoiseEditField_2Label      matlab.ui.control.Label
        MeanNoiseEditField2            matlab.ui.control.NumericEditField
        SigmaNoiseEditField_2Label     matlab.ui.control.Label
        SigmaNoiseEditField2           matlab.ui.control.NumericEditField
        WhatsymbolwasemittedEditFieldLabel  matlab.ui.control.Label
        EnterSymbolEditField           matlab.ui.control.NumericEditField
        ComputeButton2                 matlab.ui.control.Button
        BackButton                     matlab.ui.control.Button
        RestartButton                  matlab.ui.control.Button
        Meanr1Label                    matlab.ui.control.Label
        Meanr1EditField                matlab.ui.control.EditField
        Sigmar1Label                   matlab.ui.control.Label
        Sigmar1EditField               matlab.ui.control.EditField
        P0EditFieldLabel_2             matlab.ui.control.Label
        P0EditField1                   matlab.ui.control.EditField
        P1EditFieldLabel_2             matlab.ui.control.Label
        P1EditField1                   matlab.ui.control.EditField
        t0EditField_2Label             matlab.ui.control.Label
        t0EditField                    matlab.ui.control.EditField
        t1EditField_2Label             matlab.ui.control.Label
        t1EditField                    matlab.ui.control.EditField
        P0EditFieldLabel_3             matlab.ui.control.Label
        P0EditField2                   matlab.ui.control.EditField
        P1EditFieldLabel_3             matlab.ui.control.Label
        P1EditField2                   matlab.ui.control.EditField
        ThresholdfortestingEditFieldLabel_2  matlab.ui.control.Label
        ThresholdfortestingEditField1  matlab.ui.control.EditField
        LikelihoodratioEditFieldLabel_2  matlab.ui.control.Label
        LikelihoodratioEditField1      matlab.ui.control.EditField
        DecisionEditFieldLabel_2       matlab.ui.control.Label
        DecisionEditField1             matlab.ui.control.EditField
        DecisionEditFieldLabel_3       matlab.ui.control.Label
        DecisionEditField2             matlab.ui.control.EditField
        LikelihoodratioEditFieldLabel_3  matlab.ui.control.Label
        LikelihoodratioEditField2      matlab.ui.control.EditField
        ThresholdfortestingEditFieldLabel_3  matlab.ui.control.Label
        ThresholdfortestingEditField2  matlab.ui.control.EditField
    end

    
    properties (Access = public)
        type1 % true if the symbols are of the first type, else, false
        type2 % true if the symbols are of the second type, else, false
        N % num. of sampling points
        mean1 % noise mean 
        sigma1 % noise sigma
        s0_1 % s0 for the first part 
        s1_1 % s1 for the first part
        r0 % received signal samples when S0 is sent
        r1 % received signal samples when S1 is sent
        t % tiled layout used for visualization
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterParametersButton
        function EnterParametersButtonPushed(app, event)
            % if symbols are of the first type, set type1 to true and
            % type2 to false and read N, mean1 and sigma1 from EditFields
            if app.type1CheckBox.Value == 1 && app.type2CheckBox.Value == 0
                app.type1 = true;
                app.type2 = false;
                app.N = app.NumSamplingPointsEditField.Value;
                app.mean1 = app.MeanEditField.Value;
                app.sigma1 = app.SigmaEditField.Value; 
            elseif app.type1CheckBox.Value == 0 && app.type2CheckBox.Value == 1
                % if symbols are of the second type, set type1 to false and
                % type2 to true and read N, mean1 and sigma1 from EditFields
                app.type1 = false;
                app.type2 = true;
                app.N = app.NumSamplingPointsEditField.Value;
                app.mean1 = app.MeanEditField.Value;
                app.sigma1 = app.SigmaEditField.Value; 
            % if both symbol types are selected, throw error
            elseif app.type1CheckBox.Value == 1 && app.type2CheckBox.Value == 1
                errordlg('The symbols can only be of one type at a time.','Error');
            % if none of the symbol types are selected, throw error
            elseif app.type1CheckBox.Value == 0 && app.type2CheckBox.Value == 0
                errordlg('Please select the type of symbols.','Error');
            end            
        end

        % Button pushed function: 
        % CalculateReceivedSignalParametersButton
        function CalculateReceivedSignalParametersButtonPushed(app, event)
            % symbols are of type s0=0, s1=1
            if app.type1 == true && app.type2 == false 
                % defining the signals associated with the symbols
                app.s0_1=zeros(1,app.N);
                app.s1_1=ones(1,app.N);
                % noise
                n=randn(1,app.N)*app.sigma1+app.mean1;
                % received signal samples if S0 was sent
                app.r0=app.s0_1+n;
                % received signal samples if S0 was sent
                app.r1=app.s1_1+n;
                % mean and variance for s0
                mean_r0=mean(app.r0); std_r0=std(app.r0); 
                app.Meanr0EditField.Value=string(mean_r0); 
                app.Sigmar0EditField.Value=string(std_r0); 
                % mean and variance for s1
                mean_r1=mean(app.r1); std_r1=std(app.r1); 
                app.Meanr1EditField.Value=string(mean_r1); 
                app.Sigmar1EditField.Value=string(std_r1); 
            % symbols are of type s0=sin(t), s1=cos(t)
            elseif app.type1 == false && app.type2 == true
                % defining the signals associated with the symbols
                time=linspace(0,pi,app.N);
                app.s0_1=sin(time);
                app.s1_1=cos(time);
                % noise
                n=randn(1,app.N)*app.sigma1+app.mean1;
                % received signal samples if S0 was sent
                app.r0=app.s0_1+n;
                % received signal samples if S0 was sent
                app.r1=app.s1_1+n;
                % mean and variance for s0
                mean_r0=mean(app.r0); std_r0=std(app.r0); 
                app.Meanr0EditField.Value=string(mean_r0); 
                app.Sigmar0EditField.Value=string(std_r0); 
                % mean and variance for s1
                mean_r1=mean(app.r1); std_r1=std(app.r1); 
                app.Meanr1EditField.Value=string(mean_r1); 
                app.Sigmar1EditField.Value=string(std_r1); 
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Clear existing axes on panel and set new layout as 2x1
            % The axis will not appear yet. app.Panel is the UI panel handle.
            app.t = tiledlayout(app.Panel, 2, 1);
            % plotting received signal samples for both cases
            ax1 = nexttile(app.t);
            plot(ax1, app.r0, "*");
            hold(ax1,'on');
            plot(ax1, app.r1, '.k');
            hold(ax1,'off');
            legend(ax1,'r0','r1');
            title(ax1,'Received Signals Samples')
            % plotting histograms
            ax2=nexttile(app.t);
            [h0,x0]=hist(app.r0,app.N);
            plot(ax2,x0,h0);
            hold(ax2,'on');
            [h1,x1]=hist(app.r1,app.N);
            plot(ax2,x1,h1,'k');
            hold(ax2,'off');
            legend(ax2,'r0','r1');
            title(ax2,'Histograms')
        end

        % Button pushed function: ComputeButton1
        function ComputeButton1Pushed(app, event)
            % costs
            c00 = app.c00EditField1.Value;
            c11 = app.c11EditField1.Value;
            c10 = app.c10EditField1.Value;
            c01 = app.c01EditField1.Value;
            % noise parameters 
            sigma = app.SigmaNoiseEditField1.Value;
            % source emitted symbol probabilities
            P0 = str2num(app.P0EditField1.Value);
            P1 = str2num(app.P1EditField1.Value);
            % received signal samples 
            r=0.5; 
            % signals associated with the two symbols
            s0=0; 
            s1=1;
            
            probabilities_ok = 0;
            % if the probabilities are entered correctly, set
            % probabilities_ok to true and else, set it to false
            if P0+P1 ~= 1
                probabilities_ok = false;
            elseif P0+P1 == 1
                probabilities_ok = true;
            end
            
            % if the probabilities are entered correctly, compute test
            % threshold, likelihood ratio and make the decision
            if probabilities_ok == true
                % computing the test threshold
                lnK=log(P0*(c01-c00))-log(P1*(c10-c11));
                app.ThresholdfortestingEditField1.Value=string(lnK);
                % computing the likelihood ratio 
                lnLambda=sum(r.*(s1-s0))/(sigma^2)-sum(s1.^2-s0.^2)/(2*sigma^2);
                app.LikelihoodratioEditField1.Value=string(lnLambda); 
                % decision
                if lnLambda<lnK
                    app.DecisionEditField1.Value='S0 was sent';
                else
                    app.DecisionEditField1.Value='S1 was sent';
                end
                elseif probabilities_ok == false
                errordlg('The sum of the two probabilities must be 1.','Error');
            end
        end

        % Button pushed function: ComputeButton2
        function ComputeButton2Pushed(app, event)
            % defining the time vector 
            t0 = str2num(app.t0EditField.Value);
            t1 = str2num(app.t1EditField.Value);
            time=[t0,t1];
            % costs
            c00 = app.c00EditField2.Value;
            c11 = app.c11EditField2.Value;
            c10 = app.c10EditField2.Value;
            c01 = app.c01EditField2.Value; 
            % noise parameters
            mean = app.MeanNoiseEditField2.Value;
            sigma = app.SigmaNoiseEditField2.Value;
            noise = sigma.^2*randn(1)+mean;
            % source emitted symbol probabilities
            P0 = str2num(app.P0EditField2.Value);
            P1 = str2num(app.P1EditField2.Value);
            % signals associated with the two symbols
            s0=sin(time); 
            s1=cos(time);
            
            probabilities_ok = 0;
            % if the probabilities are entered correctly, set
            % probabilities_ok to true and else, set it to false
            if P0+P1 ~= 1
                probabilities_ok = false;
            elseif P0+P1 == 1
                probabilities_ok = true;
            end
            
            % if the probabilities are entered correctly, compute test
            % threshold, likelihood ratio and make the decision
            if probabilities_ok == true
            % test threshold
                K=(P0*(c01-c00))/(P1*(c10-c11));
                app.ThresholdfortestingEditField2.Value=string(K);
                emitted_symbol = app.EnterSymbolEditField.Value;
                if emitted_symbol == 0
                    r=s0+noise;
                elseif emitted_symbol == 1
                    r=s1+noise;
                end
                % computing the likelihood ratio 
                lambda=prod(1/(sigma*sqrt(2*pi))*exp(-1/(2*sigma^2)*sum((r-s1).^2)))/prod(1/(sigma*sqrt(2*pi))*exp(-1/(2*sigma^2)*sum((r-s0).^2)));
                app.LikelihoodratioEditField2.Value=string(lambda);
                % decision
                if lambda<K
                   app.DecisionEditField2.Value="S0 was sent";
                else
                   app.DecisionEditField2.Value="S1 was sent";
                end
            % if the probabilities are not entered correctly, throw error
            elseif probabilities_ok == false
                errordlg('The sum of the two probabilities must be 1.','Error');
            end
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            delete(app.StatisticalDetectionUIFigure); %close the app
            StatisticalDetection; %re-open it again
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            delete(app.StatisticalDetectionUIFigure); %close the app
            MainMenu; % open MainMenu window
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StatisticalDetectionUIFigure = uifigure('Visible', 'off');
            app.StatisticalDetectionUIFigure.Name = 'Statistical Detection ';
            app.StatisticalDetectionUIFigure.Icon = 'UpcLogo.png';
            app.MeanEditFieldLabel = uilabel(app.StatisticalDetectionUIFigure);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.StatisticalDetectionUIFigure, 'numeric');
            app.SigmaEditFieldLabel = uilabel(app.StatisticalDetectionUIFigure);
            app.SigmaEditFieldLabel.Text = 'Sigma ';
            app.SigmaEditField = uieditfield(app.StatisticalDetectionUIFigure, 'numeric');
            app.SigmaEditField.Value = 0.1;
            app.Panel = uipanel(app.StatisticalDetectionUIFigure);
            app.Panel.Title = 'Plots';
            app.PlotButton = uibutton(app.StatisticalDetectionUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.NumSamplingPointsEditFieldLabel = uilabel(app.StatisticalDetectionUIFigure);
            app.NumSamplingPointsEditFieldLabel.Text = 'Num. Sampling Points';
            app.NumSamplingPointsEditField = uieditfield(app.StatisticalDetectionUIFigure, 'numeric');
            app.NumSamplingPointsEditField.Value = 100;
            app.NoiseParametersLabel = uilabel(app.StatisticalDetectionUIFigure);
            app.NoiseParametersLabel.Text = 'Noise Parameters: ';
            app.ReceivedSignalParametersLabel = uilabel(app.StatisticalDetectionUIFigure);
            app.ReceivedSignalParametersLabel.Text = 'Received Signal Parameters: ';
            app.CalculateReceivedSignalParametersButton = uibutton(app.StatisticalDetectionUIFigure, 'push');
            app.CalculateReceivedSignalParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateReceivedSignalParametersButtonPushed, true);
            app.CalculateReceivedSignalParametersButton.Text = 'Calculate Received Signal Parameters';
            app.EnterParametersButton = uibutton(app.StatisticalDetectionUIFigure, 'push');
            app.EnterParametersButton.ButtonPushedFcn = createCallbackFcn(app, @EnterParametersButtonPushed, true);
            app.EnterParametersButton.Text = 'Enter Parameters';
            app.SymbolsTypeLabel = uilabel(app.StatisticalDetectionUIFigure);
            app.SymbolsTypeLabel.Text = 'Symbols Type:';
            app.type1CheckBox = uicheckbox(app.StatisticalDetectionUIFigure);
            app.type1CheckBox.Text = 's0=0, s1=1';
            app.type2CheckBox = uicheckbox(app.StatisticalDetectionUIFigure);
            app.type2CheckBox.Text = 's0=sin(t), s1=cos(t)';
            app.Meanr0Label = uilabel(app.StatisticalDetectionUIFigure);
            app.Meanr0Label.Text = 'Mean (r0)';
            app.Meanr0EditField = uieditfield(app.StatisticalDetectionUIFigure, 'text');
            app.Meanr0EditField.Editable = 'off';
            app.Sigmar0Label = uilabel(app.StatisticalDetectionUIFigure);
            app.Sigmar0Label.Text = 'Sigma (r0)';
            app.Sigmar0EditField = uieditfield(app.StatisticalDetectionUIFigure, 'text');
            app.Sigmar0EditField.Editable = 'off';
            app.c00EditFieldLabel = uilabel(app.StatisticalDetectionUIFigure);
            app.c00EditFieldLabel.Text = 'c00';
            app.c00EditField1 = uieditfield(app.StatisticalDetectionUIFigure, 'numeric');
            app.c00EditField1.Limits = [0 1];
            app.c00EditField1.ValueDisplayFormat = '%.0f';
            app.c11EditFieldLabel = uilabel(app.StatisticalDetectionUIFigure);
            app.c11EditFieldLabel.Text = 'c11';
            app.c11EditField1 = uieditfield(app.StatisticalDetectionUIFigure, 'numeric');
            app.c11EditField1.Limits = [0 1];
            app.c11EditField1.ValueDisplayFormat = '%.0f';
            app.c01EditFieldLabel = uilabel(app.StatisticalDetectionUIFigure);
            app.c01EditFieldLabel.Text = 'c01';
            app.c01EditField1 = uieditfield(app.StatisticalDetectionUIFigure, 'numeric');
            app.c01EditField1.Limits = [0 1];
            app.c01EditField1.ValueDisplayFormat = '%.0f';
            app.c01EditField1.Value = 1;
            app.c10EditField_2Label = uilabel(app.StatisticalDetectionUIFigure);
            app.c10EditField_2Label.Text = 'c10';
            app.c10EditField1 = uieditfield(app.StatisticalDetectionUIFigure, 'numeric');
            app.c10EditField1.Limits = [0 1];
            app.c10EditField1.ValueDisplayFormat = '%.0f';
            app.c10EditField1.Value = 1;
            app.ComputeButton1 = uibutton(app.StatisticalDetectionUIFigure, 'push');
            app.ComputeButton1.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton1Pushed, true);
            app.ComputeButton1.Text = 'Compute';
            app.SigmaNoiseEditFieldLabel = uilabel(app.StatisticalDetectionUIFigure);
            app.SigmaNoiseEditFieldLabel.Text = 'Sigma Noise';
            app.SigmaNoiseEditField1 = uieditfield(app.StatisticalDetectionUIFigure, 'numeric');
            app.SigmaNoiseEditField1.Value = 0.5;
            app.Decisionfors00s11Label = uilabel(app.StatisticalDetectionUIFigure);
            app.Decisionfors00s11Label.Text = 'Decision for s0=0, s1=1 ';
            app.Decisionfors0sints1costLabel = uilabel(app.StatisticalDetectionUIFigure);
            app.Decisionfors0sints1costLabel.Text = 'Decision for s0=sin(t), s1=cos(t) ';
            app.c00EditField_2Label = uilabel(app.StatisticalDetectionUIFigure);
            app.c00EditField_2Label.Text = 'c00';
            app.c00EditField2 = uieditfield(app.StatisticalDetectionUIFigure, 'numeric');
            app.c00EditField2.Limits = [0 1];
            app.c00EditField2.ValueDisplayFormat = '%.0f';
            app.c11EditField_2Label = uilabel(app.StatisticalDetectionUIFigure);
            app.c11EditField_2Label.Text = 'c11';
            app.c11EditField2 = uieditfield(app.StatisticalDetectionUIFigure, 'numeric');
            app.c11EditField2.Limits = [0 1];
            app.c11EditField2.ValueDisplayFormat = '%.0f';
            app.c01EditField_2Label = uilabel(app.StatisticalDetectionUIFigure);
            app.c01EditField_2Label.Text = 'c01';
            app.c01EditField2 = uieditfield(app.StatisticalDetectionUIFigure, 'numeric');
            app.c01EditField2.Limits = [0 1];
            app.c01EditField2.ValueDisplayFormat = '%.0f';
            app.c01EditField2.Value = 1;
            app.c10EditField_3Label = uilabel(app.StatisticalDetectionUIFigure);
            app.c10EditField_3Label.Text = 'c10';
            app.c10EditField2 = uieditfield(app.StatisticalDetectionUIFigure, 'numeric');
            app.c10EditField2.Limits = [0 1];
            app.c10EditField2.ValueDisplayFormat = '%.0f';
            app.c10EditField2.Value = 1;
            app.MeanNoiseEditField_2Label = uilabel(app.StatisticalDetectionUIFigure);
            app.MeanNoiseEditField_2Label.Text = 'Mean Noise';
            app.MeanNoiseEditField2 = uieditfield(app.StatisticalDetectionUIFigure, 'numeric');
            app.SigmaNoiseEditField_2Label = uilabel(app.StatisticalDetectionUIFigure);
            app.SigmaNoiseEditField_2Label.Text = 'Sigma Noise';
            app.SigmaNoiseEditField2 = uieditfield(app.StatisticalDetectionUIFigure, 'numeric');
            app.SigmaNoiseEditField2.Value = 0.1;
            app.WhatsymbolwasemittedEditFieldLabel = uilabel(app.StatisticalDetectionUIFigure);
            app.WhatsymbolwasemittedEditFieldLabel.Text = 'What symbol was emitted?';
            app.EnterSymbolEditField = uieditfield(app.StatisticalDetectionUIFigure, 'numeric');
            app.EnterSymbolEditField.Limits = [0 1];
            app.EnterSymbolEditField.ValueDisplayFormat = '%.0f';
            app.ComputeButton2 = uibutton(app.StatisticalDetectionUIFigure, 'push');
            app.ComputeButton2.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton2Pushed, true);
            app.ComputeButton2.Text = 'Compute';
            app.BackButton = uibutton(app.StatisticalDetectionUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.RestartButton = uibutton(app.StatisticalDetectionUIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.Meanr1Label = uilabel(app.StatisticalDetectionUIFigure);
            app.Meanr1Label.Text = 'Mean (r1)';
            app.Meanr1EditField = uieditfield(app.StatisticalDetectionUIFigure, 'text');
            app.Meanr1EditField.Editable = 'off';
            app.Sigmar1Label = uilabel(app.StatisticalDetectionUIFigure);
            app.Sigmar1Label.Text = 'Sigma (r1)';
            app.Sigmar1EditField = uieditfield(app.StatisticalDetectionUIFigure, 'text');
            app.Sigmar1EditField.Editable = 'off';
            app.P0EditFieldLabel_2 = uilabel(app.StatisticalDetectionUIFigure);
            app.P0EditFieldLabel_2.Text = 'P0';
            app.P0EditField1 = uieditfield(app.StatisticalDetectionUIFigure, 'text');
            app.P0EditField1.Value = '0.5';
            app.P1EditFieldLabel_2 = uilabel(app.StatisticalDetectionUIFigure);
            app.P1EditFieldLabel_2.Text = 'P1';
            app.P1EditField1 = uieditfield(app.StatisticalDetectionUIFigure, 'text');
            app.P1EditField1.Value = '0.5';
            app.t0EditField_2Label = uilabel(app.StatisticalDetectionUIFigure);
            app.t0EditField_2Label.Text = 't(0)';
            app.t0EditField = uieditfield(app.StatisticalDetectionUIFigure, 'text');
            app.t0EditField.Value = 'pi/2';
            app.t1EditField_2Label = uilabel(app.StatisticalDetectionUIFigure);
            app.t1EditField_2Label.Text = 't(1)';
            app.t1EditField = uieditfield(app.StatisticalDetectionUIFigure, 'text');
            app.t1EditField.Value = '2*pi/3';
            app.P0EditFieldLabel_3 = uilabel(app.StatisticalDetectionUIFigure);
            app.P0EditFieldLabel_3.Text = 'P0';
            app.P0EditField2 = uieditfield(app.StatisticalDetectionUIFigure, 'text');
            app.P0EditField2.Value = '1/3';
            app.P1EditFieldLabel_3 = uilabel(app.StatisticalDetectionUIFigure);
            app.P1EditFieldLabel_3.Text = 'P1';
            app.P1EditField2 = uieditfield(app.StatisticalDetectionUIFigure, 'text');
            app.P1EditField2.Value = '2/3';
            app.ThresholdfortestingEditFieldLabel_2 = uilabel(app.StatisticalDetectionUIFigure);
            app.ThresholdfortestingEditFieldLabel_2.Text = 'Threshold for testing';
            app.ThresholdfortestingEditField1 = uieditfield(app.StatisticalDetectionUIFigure, 'text');
            app.ThresholdfortestingEditField1.Editable = 'off';
            app.LikelihoodratioEditFieldLabel_2 = uilabel(app.StatisticalDetectionUIFigure);
            app.LikelihoodratioEditFieldLabel_2.Text = 'Likelihood ratio';
            app.LikelihoodratioEditField1 = uieditfield(app.StatisticalDetectionUIFigure, 'text');
            app.LikelihoodratioEditField1.Editable = 'off';
            app.DecisionEditFieldLabel_2 = uilabel(app.StatisticalDetectionUIFigure);
            app.DecisionEditFieldLabel_2.Text = 'Decision';
            app.DecisionEditField1 = uieditfield(app.StatisticalDetectionUIFigure, 'text');
            app.DecisionEditField1.Editable = 'off';
            app.DecisionEditFieldLabel_3 = uilabel(app.StatisticalDetectionUIFigure);
            app.DecisionEditFieldLabel_3.Text = 'Decision';
            app.DecisionEditField2 = uieditfield(app.StatisticalDetectionUIFigure, 'text');
            app.DecisionEditField2.Editable = 'off';
            app.LikelihoodratioEditFieldLabel_3 = uilabel(app.StatisticalDetectionUIFigure);
            app.LikelihoodratioEditFieldLabel_3.Text = 'Likelihood ratio';
            app.LikelihoodratioEditField2 = uieditfield(app.StatisticalDetectionUIFigure, 'text');
            app.LikelihoodratioEditField2.Editable = 'off';
            app.ThresholdfortestingEditFieldLabel_3 = uilabel(app.StatisticalDetectionUIFigure);
            app.ThresholdfortestingEditFieldLabel_3.Text = 'Threshold for testing';
            app.ThresholdfortestingEditField2 = uieditfield(app.StatisticalDetectionUIFigure, 'text');
            app.ThresholdfortestingEditField2.Editable = 'off';
            % Show the figure after all components are created
            app.StatisticalDetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StatisticalDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StatisticalDetectionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StatisticalDetectionUIFigure)
        end
    end
end